classdef VJG_03JUN25_A < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VehicleJSONGeneratorUIFigure  matlab.ui.Figure
        WeaponSystemsPanel            matlab.ui.container.Panel
        EquippedWeaponDropDown        matlab.ui.control.DropDown
        EquippedWeaponDropDownLabel   matlab.ui.control.Label
        RangeEditField_2              matlab.ui.control.NumericEditField
        RangeEditField_2Label         matlab.ui.control.Label
        WeaponHealthEditField         matlab.ui.control.NumericEditField
        WeaponHealthEditFieldLabel    matlab.ui.control.Label
        PowerEditField                matlab.ui.control.NumericEditField
        PowerEditFieldLabel           matlab.ui.control.Label
        RechargeRateEditField         matlab.ui.control.NumericEditField
        RechargeRateLabel             matlab.ui.control.Label
        MinRequirementEditField       matlab.ui.control.NumericEditField
        MinRequirementLabel           matlab.ui.control.Label
        CapacityEditField             matlab.ui.control.NumericEditField
        CapacityEditFieldLabel        matlab.ui.control.Label
        ConsumptionEditField          matlab.ui.control.NumericEditField
        ConsumptionEditFieldLabel     matlab.ui.control.Label
        UAVPanel_2                    matlab.ui.container.Panel
        VariantNameEditField_4        matlab.ui.control.EditField
        VariantNameEditField_4Label   matlab.ui.control.Label
        AltitudeEditField             matlab.ui.control.NumericEditField
        AltitudeEditFieldLabel        matlab.ui.control.Label
        SubcategoryButtonGroup_11     matlab.ui.container.ButtonGroup
        ShadowButton_2                matlab.ui.control.ToggleButton
        RavenButton_2                 matlab.ui.control.ToggleButton
        DetectionRadiusEditField_4    matlab.ui.control.NumericEditField
        DetectionRadiusEditField_4Label  matlab.ui.control.Label
        RangeEditField_7              matlab.ui.control.NumericEditField
        RangeEditField_7Label         matlab.ui.control.Label
        FuelLevelEditField_4          matlab.ui.control.NumericEditField
        FuelLevelEditField_4Label     matlab.ui.control.Label
        SpeedEditField_4              matlab.ui.control.NumericEditField
        SpeedEditField_4Label         matlab.ui.control.Label
        HealthEditField_4             matlab.ui.control.NumericEditField
        HealthEditField_4Label        matlab.ui.control.Label
        Image_12                      matlab.ui.control.Image
        VehiclePanel                  matlab.ui.container.Panel
        VehicleDropDown               matlab.ui.control.DropDown
        VehicleDropDownLabel          matlab.ui.control.Label
        VariantNameEditField          matlab.ui.control.EditField
        VariantNameEditFieldLabel     matlab.ui.control.Label
        DetectionRadiusEditField      matlab.ui.control.NumericEditField
        DetectionRadiusLabel_2        matlab.ui.control.Label
        RangeEditField                matlab.ui.control.NumericEditField
        RangeEditFieldLabel           matlab.ui.control.Label
        FuelLevelEditField            matlab.ui.control.NumericEditField
        FuelLevelEditFieldLabel       matlab.ui.control.Label
        SpeedEditField                matlab.ui.control.NumericEditField
        SpeedEditFieldLabel           matlab.ui.control.Label
        HealthEditField               matlab.ui.control.NumericEditField
        HealthEditFieldLabel          matlab.ui.control.Label
        Image_1                       matlab.ui.control.Image
        CategoryDropDown              matlab.ui.control.DropDown
        CategoryDropDownLabel         matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        ExportButton                  matlab.ui.control.Button
        ImportButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        VehicleData % Description
        VehicleNameMap
    end      
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
  % Get path to the current app's folder
    imgFolder = fileparts(mfilename('fullpath'));

        % Combat

    app.VehicleData.combat.bradley = struct('health', 75, 'speed', 60, 'range', 300, 'fuel', 100, 'detectionRadius', 100);
    app.VehicleData.combat.M1A2 = struct('health', 65, 'speed', 60, 'range', 300, 'fuel', 100, 'detectionRadius', 100);
    app.VehicleData.combat.DEMSHORAD = struct('health', 55, 'speed', 60, 'range', 300, 'fuel', 100, 'detectionRadius', 100);
    app.VehicleData.combat.IFPCHEL = struct('health', 45, 'speed', 60, 'range', 300, 'fuel', 100, 'detectionRadius', 100);
    

        % Combat Support
    app.VehicleData.combatsupport.jltv = struct('health', 75, 'speed', 80, 'range', 300, 'fuel', 100, 'detectionRadius', 100);
    app.VehicleData.combatsupport.M109paladin = struct('health', 75, 'speed', 50, 'range', 300, 'fuel', 100, 'detectionRadius', 100);
    app.VehicleData.combatsupport.STRYKERICV = struct('health', 75, 'speed', 40, 'range', 300, 'fuel', 100, 'detectionRadius', 100);
    app.VehicleData.combatsupport.MORTARCARRIER = struct('health', 75, 'speed', 30, 'range', 300, 'fuel', 100, 'detectionRadius', 100);
    app.VehicleData.combatsupport.ABV = struct('health', 75, 'speed', 20, 'range', 300, 'fuel', 100, 'detectionRadius', 100);

        % Combat Service Support
    app.VehicleData.combatservicesupport.fmtv = struct('health', 75, 'speed', 60, 'range', 400, 'fuel', 100, 'detectionRadius', 100);
    app.VehicleData.combatservicesupport.Humveeambulance = struct('health', 75, 'speed', 60, 'range', 500, 'fuel', 100, 'detectionRadius', 100);
    app.VehicleData.combatservicesupport.trackedambulance = struct('health', 75, 'speed', 60, 'range', 600, 'fuel', 100, 'detectionRadius', 100);
    app.VehicleData.combatservicesupport.PLS = struct('health', 75, 'speed', 60, 'range', 700, 'fuel', 100, 'detectionRadius', 100);
    app.VehicleData.combatservicesupport.M88HERCULES = struct('health', 75, 'speed', 60, 'range', 800, 'fuel', 100, 'detectionRadius', 100);

    
        end

        % Value changed function: CategoryDropDown
        function CategoryDropDownValueChanged(app, event)
    % Get selected category
    categoryUI = app.CategoryDropDown.Value;

    % Hide panel if "(Blank)" is selected
    if strcmp(categoryUI, '(Blank)')
        app.VehiclePanel.Visible = false;
        return;
    end

    % Normalize UI text into struct-compatible field name
    category = lower(strrep(categoryUI, ' ', ''));

    app.VehiclePanel.Visible = true;

    % Now the variable 'category' is defined, so you can use it in the switch
    switch category
        case 'combat'
            displayNames = {'Bradley', 'Abrams', 'DEMSHORAD', 'IFPC-HEL'};
            structKeys   = {'bradley', 'M1A2', 'DEMSHORAD', 'IFPCHEL'};

        case 'combatsupport'
            displayNames = {'JLTV', 'Paladin', 'Stryker', 'Mortar Carrier', 'ABV'};
            structKeys   = {'jltv', 'M109paladin', 'STRYKERICV', 'MORTARCARRIER', 'ABV'};

        case 'combatservicesupport'
            displayNames = {'FMTV', 'HMMWV Ambulance', 'Tracked Ambulance', 'PLS', 'Hercules'};
            structKeys   = {'fmtv', 'Humveeambulance', 'trackedambulance', 'PLS', 'M88HERCULES'};

        otherwise
            displayNames = {};
            structKeys = {};
    end

    % Update dropdown
    app.VehicleDropDown.Items = displayNames;
    app.VehicleDropDown.Value = displayNames{1};

    % Create mapping
    nameMap = containers.Map();  % ✅ use containers.Map
for i = 1:numel(displayNames)
    nameMap(displayNames{i}) = structKeys{i};
end
    app.VehicleNameMap = nameMap;

    % Trigger vehicle panel update
    VehicleDropDownValueChanged(app);


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.HealthEditField.Value=1;
            app.SpeedEditField.Value=1;
            app.FuelLevelEditField.Value=1;
            app.RangeEditField.Value=1;
            app.DetectionRadiusEditField.Value=1;
   
        end

        % Value changed function: VehicleDropDown
        function VehicleDropDownValueChanged(app, event)
     
 % Get current selections
    categoryUI = app.CategoryDropDown.Value;
    category = lower(strrep(categoryUI, ' ', ''));

    uiName = string(app.VehicleDropDown.Value);

    % Get the struct field name from the map
    if isKey(app.VehicleNameMap, uiName)
        vehicleKey = app.VehicleNameMap(uiName);
    else
        uialert(app.VehicleJSONGeneratorUIFigure, 'Vehicle not recognized', 'Error');
        return;
    end

    % Retrieve vehicle data
    if isfield(app.VehicleData.(category), vehicleKey)
        tmp = app.VehicleData.(category).(vehicleKey);

        app.HealthEditField.Value = tmp.health;
        app.SpeedEditField.Value = tmp.speed;
        app.FuelLevelEditField.Value = tmp.fuel;
        app.RangeEditField.Value = tmp.range;
        app.DetectionRadiusEditField.Value = tmp.detectionRadius;
    end
        % Set image
        imgFolder = fileparts(mfilename('fullpath'));
        switch uiName
            case 'Bradley', file = 'BFV.jpg';
            case 'Abrams', file = 'M1A2.jpg';
            case 'DEMSHORAD', file = 'DEMSHORAD.jpeg';
            case 'IFPC-HEL', file = 'IFPCHEL.png';
            case 'JLTV', file = 'JLTV.jpg';
            case 'Paladin', file = 'm109paladin.jpg';
            case 'Stryker', file = 'STRYKERICV.jpg';
            case 'Mortar Carrier', file = 'MORTARCARRIER.jpg';
            case 'ABV', file = 'ABV.jpg';
            case 'FMTV', file = 'FMTV.jpg';
            case 'HMMWV Ambulance', file = 'humveeambulance.jpg';
            case 'Tracked Ambulance', file = 'trackedambulance.jpeg';
            case 'PLS', file = 'PLS.jpg';
            case 'Hercules', file = 'M88HERCULES.jpg';
            otherwise, file = '';
        end

        imagePath = fullfile(imgFolder, file);
if isfile(imagePath)
    app.Image_1.ImageSource = imagePath;
else
    app.Image_1.ImageSource = ''; % Clear or default image
    warning('Missing image file: %s', imagePath);
end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
             % Collect data from the GUI
    vehicleData = struct();
    vehicleData.category       = app.CategoryDropDown.Value;
    vehicleData.variantName    = app.VariantNameEditField.Value;
    vehicleData.vehicleType    = app.VehicleDropDown.Value;
    vehicleData.health         = app.HealthEditField.Value;
    vehicleData.speed          = app.SpeedEditField.Value;
    vehicleData.fuel           = app.FuelLevelEditField.Value;
    vehicleData.range          = app.RangeEditField.Value;
    vehicleData.detectionRadius = app.DetectionRadiusEditField.Value;

    % Get the variant name and clean it for file use
rawName = app.VariantNameEditField.Value;
if isempty(rawName)
    rawName = 'UnnamedVariant';
end

% Remove illegal characters for filenames (like / \ : * ? " < > |)
cleanName = regexprep(rawName, '[\/:*?"<>|]', '_');
defaultFileName = [cleanName, '.json'];

% Prompt user to save with default filename
[file, path] = uiputfile(defaultFileName, 'Save Vehicle JSON');

    % Convert struct to JSON
    jsonText = jsonencode(vehicleData, 'PrettyPrint', true);

    % Write to file
    fid = fopen(fullfile(path, file), 'w');
    if fid == -1
        uialert(app.VehicleJSONGeneratorUIFigure, 'Could not open file for writing.', 'File Error');
        return;
    end
    fwrite(fid, jsonText, 'char');
    fclose(fid);

    % Confirmation message
    uialert(app.VehicleJSONGeneratorUIFigure, 'Vehicle configuration exported successfully!', 'Export Complete');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.VehicleJSONGeneratorUIFigure = uifigure('Visible', 'off');
            app.VehicleJSONGeneratorUIFigure.Name = 'Vehicle JSON Generator';
            app.ImportButton = uibutton(app.VehicleJSONGeneratorUIFigure, 'push');
            app.ImportButton.Text = 'Import';
            app.ExportButton = uibutton(app.VehicleJSONGeneratorUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ResetButton = uibutton(app.VehicleJSONGeneratorUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CategoryDropDownLabel = uilabel(app.VehicleJSONGeneratorUIFigure);
            app.CategoryDropDownLabel.Text = 'Category';
            app.CategoryDropDown = uidropdown(app.VehicleJSONGeneratorUIFigure);
            app.CategoryDropDown.Items = {'(Select Category)', 'Combat', 'Combat Support', 'Combat Service Support'};
            app.CategoryDropDown.ValueChangedFcn = createCallbackFcn(app, @CategoryDropDownValueChanged, true);
            app.CategoryDropDown.Value = '(Select Category)';
            app.VehiclePanel = uipanel(app.VehicleJSONGeneratorUIFigure);
            app.VehiclePanel.Title = 'Combat Platforms';
            app.VehiclePanel.Visible = 'off';
            app.Image_1 = uiimage(app.VehiclePanel);
            app.Image_1.ImageSource = fullfile(pathToMLAPP, 'BFV.jpg');
            app.HealthEditFieldLabel = uilabel(app.VehiclePanel);
            app.HealthEditFieldLabel.Text = 'Health';
            app.HealthEditField = uieditfield(app.VehiclePanel, 'numeric');
            app.HealthEditField.Limits = [0 75];
            app.HealthEditField.Value = 1;
            app.SpeedEditFieldLabel = uilabel(app.VehiclePanel);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.VehiclePanel, 'numeric');
            app.FuelLevelEditFieldLabel = uilabel(app.VehiclePanel);
            app.FuelLevelEditFieldLabel.Text = 'Fuel Level';
            app.FuelLevelEditField = uieditfield(app.VehiclePanel, 'numeric');
            app.RangeEditFieldLabel = uilabel(app.VehiclePanel);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.VehiclePanel, 'numeric');
            app.DetectionRadiusLabel_2 = uilabel(app.VehiclePanel);
            app.DetectionRadiusLabel_2.Text = {'Detection'; 'Radius'};
            app.DetectionRadiusEditField = uieditfield(app.VehiclePanel, 'numeric');
            app.VariantNameEditFieldLabel = uilabel(app.VehiclePanel);
            app.VariantNameEditFieldLabel.Text = 'Variant Name';
            app.VariantNameEditField = uieditfield(app.VehiclePanel, 'text');
            app.VehicleDropDownLabel = uilabel(app.VehiclePanel);
            app.VehicleDropDownLabel.Text = 'Vehicle';
            app.VehicleDropDown = uidropdown(app.VehiclePanel);
            app.VehicleDropDown.Items = {};
            app.VehicleDropDown.ValueChangedFcn = createCallbackFcn(app, @VehicleDropDownValueChanged, true);
            app.VehicleDropDown.Value = {};
            app.UAVPanel_2 = uipanel(app.VehicleJSONGeneratorUIFigure);
            app.UAVPanel_2.Title = 'UAV';
            app.Image_12 = uiimage(app.UAVPanel_2);
            app.Image_12.ImageSource = fullfile(pathToMLAPP, 'RQ11.jpg');
            app.HealthEditField_4Label = uilabel(app.UAVPanel_2);
            app.HealthEditField_4Label.Text = 'Health';
            app.HealthEditField_4 = uieditfield(app.UAVPanel_2, 'numeric');
            app.SpeedEditField_4Label = uilabel(app.UAVPanel_2);
            app.SpeedEditField_4Label.Text = 'Speed';
            app.SpeedEditField_4 = uieditfield(app.UAVPanel_2, 'numeric');
            app.FuelLevelEditField_4Label = uilabel(app.UAVPanel_2);
            app.FuelLevelEditField_4Label.Text = 'Fuel Level';
            app.FuelLevelEditField_4 = uieditfield(app.UAVPanel_2, 'numeric');
            app.RangeEditField_7Label = uilabel(app.UAVPanel_2);
            app.RangeEditField_7Label.Text = 'Range';
            app.RangeEditField_7 = uieditfield(app.UAVPanel_2, 'numeric');
            app.DetectionRadiusEditField_4Label = uilabel(app.UAVPanel_2);
            app.DetectionRadiusEditField_4Label.Text = {'Detection'; 'Radius'};
            app.DetectionRadiusEditField_4 = uieditfield(app.UAVPanel_2, 'numeric');
            app.SubcategoryButtonGroup_11 = uibuttongroup(app.UAVPanel_2);
            app.SubcategoryButtonGroup_11.Title = 'Subcategory';
            app.RavenButton_2 = uitogglebutton(app.SubcategoryButtonGroup_11);
            app.RavenButton_2.Text = 'Raven';
            app.RavenButton_2.Value = true;
            app.ShadowButton_2 = uitogglebutton(app.SubcategoryButtonGroup_11);
            app.ShadowButton_2.Text = 'Shadow';
            app.AltitudeEditFieldLabel = uilabel(app.UAVPanel_2);
            app.AltitudeEditFieldLabel.Text = 'Altitude';
            app.AltitudeEditField = uieditfield(app.UAVPanel_2, 'numeric');
            app.VariantNameEditField_4Label = uilabel(app.UAVPanel_2);
            app.VariantNameEditField_4Label.Text = 'Variant Name';
            app.VariantNameEditField_4 = uieditfield(app.UAVPanel_2, 'text');
            app.WeaponSystemsPanel = uipanel(app.VehicleJSONGeneratorUIFigure);
            app.WeaponSystemsPanel.Title = 'Weapon Systems';
            app.ConsumptionEditFieldLabel = uilabel(app.WeaponSystemsPanel);
            app.ConsumptionEditFieldLabel.Text = 'Consumption';
            app.ConsumptionEditField = uieditfield(app.WeaponSystemsPanel, 'numeric');
            app.CapacityEditFieldLabel = uilabel(app.WeaponSystemsPanel);
            app.CapacityEditFieldLabel.Text = 'Capacity';
            app.CapacityEditField = uieditfield(app.WeaponSystemsPanel, 'numeric');
            app.MinRequirementLabel = uilabel(app.WeaponSystemsPanel);
            app.MinRequirementLabel.Text = 'Min Requirement';
            app.MinRequirementEditField = uieditfield(app.WeaponSystemsPanel, 'numeric');
            app.RechargeRateLabel = uilabel(app.WeaponSystemsPanel);
            app.RechargeRateLabel.Text = 'Recharge Rate';
            app.RechargeRateEditField = uieditfield(app.WeaponSystemsPanel, 'numeric');
            app.PowerEditFieldLabel = uilabel(app.WeaponSystemsPanel);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.WeaponSystemsPanel, 'numeric');
            app.WeaponHealthEditFieldLabel = uilabel(app.WeaponSystemsPanel);
            app.WeaponHealthEditFieldLabel.Text = 'Weapon Health';
            app.WeaponHealthEditField = uieditfield(app.WeaponSystemsPanel, 'numeric');
            app.RangeEditField_2Label = uilabel(app.WeaponSystemsPanel);
            app.RangeEditField_2Label.Text = 'Range';
            app.RangeEditField_2 = uieditfield(app.WeaponSystemsPanel, 'numeric');
            app.EquippedWeaponDropDownLabel = uilabel(app.WeaponSystemsPanel);
            app.EquippedWeaponDropDownLabel.Text = 'Equipped Weapon';
            app.EquippedWeaponDropDown = uidropdown(app.WeaponSystemsPanel);
            app.EquippedWeaponDropDown.Items = {'None', 'DEW', 'RadioEnergy', 'Kinetic'};
            app.EquippedWeaponDropDown.Value = 'None';
            % Show the figure after all components are created
            app.VehicleJSONGeneratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VJG_03JUN25_A

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VehicleJSONGeneratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VehicleJSONGeneratorUIFigure)
        end
    end
end