classdef VJG_25MAY25_A < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VehicleJSONGeneratorUIFigure   matlab.ui.Figure
        WeaponSystemsPanel             matlab.ui.container.Panel
        EquippedWeaponDropDown         matlab.ui.control.DropDown
        EquippedWeaponDropDownLabel    matlab.ui.control.Label
        RangeEditField_2               matlab.ui.control.NumericEditField
        RangeEditField_2Label          matlab.ui.control.Label
        WeaponHealthEditField          matlab.ui.control.NumericEditField
        WeaponHealthEditFieldLabel     matlab.ui.control.Label
        PowerEditField                 matlab.ui.control.NumericEditField
        PowerEditFieldLabel            matlab.ui.control.Label
        RechargeRateEditField          matlab.ui.control.NumericEditField
        RechargeRateLabel              matlab.ui.control.Label
        MinRequirementEditField        matlab.ui.control.NumericEditField
        MinRequirementLabel            matlab.ui.control.Label
        CapacityEditField              matlab.ui.control.NumericEditField
        CapacityEditFieldLabel         matlab.ui.control.Label
        ConsumptionEditField           matlab.ui.control.NumericEditField
        ConsumptionEditFieldLabel      matlab.ui.control.Label
        UAVPanel_2                     matlab.ui.container.Panel
        VariantNameEditField_4         matlab.ui.control.EditField
        VariantNameEditField_4Label    matlab.ui.control.Label
        AltitudeEditField              matlab.ui.control.NumericEditField
        AltitudeEditFieldLabel         matlab.ui.control.Label
        SubcategoryButtonGroup_11      matlab.ui.container.ButtonGroup
        ShadowButton_2                 matlab.ui.control.ToggleButton
        RavenButton_2                  matlab.ui.control.ToggleButton
        DetectionRadiusEditField_4     matlab.ui.control.NumericEditField
        DetectionRadiusEditField_4Label  matlab.ui.control.Label
        RangeEditField_7               matlab.ui.control.NumericEditField
        RangeEditField_7Label          matlab.ui.control.Label
        FuelLevelEditField_4           matlab.ui.control.NumericEditField
        FuelLevelEditField_4Label      matlab.ui.control.Label
        SpeedEditField_4               matlab.ui.control.NumericEditField
        SpeedEditField_4Label          matlab.ui.control.Label
        HealthEditField_4              matlab.ui.control.NumericEditField
        HealthEditField_4Label         matlab.ui.control.Label
        Image_12                       matlab.ui.control.Image
        CombatServiceSupportPlatformsPanel_2  matlab.ui.container.Panel
        VehicleDropDown_3              matlab.ui.control.DropDown
        VehicleDropDown_3Label         matlab.ui.control.Label
        VariantNameEditField_3         matlab.ui.control.EditField
        VariantNameEditField_3Label    matlab.ui.control.Label
        DetectionRadiusEditField_3     matlab.ui.control.NumericEditField
        DetectionRadiusEditField_3Label  matlab.ui.control.Label
        RangeEditField_5               matlab.ui.control.NumericEditField
        RangeEditField_5Label          matlab.ui.control.Label
        FuelLevelEditField_3           matlab.ui.control.NumericEditField
        FuelLevelEditField_3Label      matlab.ui.control.Label
        SpeedEditField_3               matlab.ui.control.NumericEditField
        SpeedEditField_3Label          matlab.ui.control.Label
        HealthEditField_3              matlab.ui.control.NumericEditField
        HealthEditField_3Label         matlab.ui.control.Label
        Image_11                       matlab.ui.control.Image
        CombatSupportPlatformsPanel_2  matlab.ui.container.Panel
        VehicleDropDown_2              matlab.ui.control.DropDown
        VehicleDropDown_2Label         matlab.ui.control.Label
        VariantNameEditField_2         matlab.ui.control.EditField
        VariantNameEditField_2Label    matlab.ui.control.Label
        DetectionRadiusEditField_2     matlab.ui.control.NumericEditField
        DetectionRadiusEditField_2Label  matlab.ui.control.Label
        RangeEditField_3               matlab.ui.control.NumericEditField
        RangeEditField_3Label          matlab.ui.control.Label
        FuelLevelEditField_2           matlab.ui.control.NumericEditField
        FuelLevelEditField_2Label      matlab.ui.control.Label
        SpeedEditField_2               matlab.ui.control.NumericEditField
        SpeedEditField_2Label          matlab.ui.control.Label
        HealthEditField_2              matlab.ui.control.NumericEditField
        HealthEditField_2Label         matlab.ui.control.Label
        Image_10                       matlab.ui.control.Image
        CombatPlatformsPanel           matlab.ui.container.Panel
        VehicleDropDown                matlab.ui.control.DropDown
        VehicleDropDownLabel           matlab.ui.control.Label
        VariantNameEditField           matlab.ui.control.EditField
        VariantNameEditFieldLabel      matlab.ui.control.Label
        DetectionRadiusEditField       matlab.ui.control.NumericEditField
        DetectionRadiusLabel_2         matlab.ui.control.Label
        RangeEditField                 matlab.ui.control.NumericEditField
        RangeEditFieldLabel            matlab.ui.control.Label
        FuelLevelEditField             matlab.ui.control.NumericEditField
        FuelLevelEditFieldLabel        matlab.ui.control.Label
        SpeedEditField                 matlab.ui.control.NumericEditField
        SpeedEditFieldLabel            matlab.ui.control.Label
        HealthEditField                matlab.ui.control.NumericEditField
        HealthEditFieldLabel           matlab.ui.control.Label
        Image_9                        matlab.ui.control.Image
        CategoryDropDown               matlab.ui.control.DropDown
        CategoryDropDownLabel          matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        ExportButton                   matlab.ui.control.Button
        ImportButton                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
 % Get path to the current app's folder
    imgFolder = fileparts(mfilename('fullpath'));
    % another little comment - let's delete this line

    
    % Set default image for Combat Platforms panel
    app.Image_9.ImageSource = fullfile(imgFolder, 'BFV.jpg');

    % Set default image for Combat Support Platforms panel
    app.Image_10.ImageSource = fullfile(imgFolder, 'JLTV.jpg');

    % Set default image for Combat Service Support Platforms panel
    app.Image_11.ImageSource = fullfile(imgFolder, 'FMTV.jpg');
        end

        % Value changed function: CategoryDropDown
        function CategoryDropDownValueChanged(app, event)
  selectedCategory = app.CategoryDropDown.Value;
            
            % Hide all panels first
    app.CombatPlatformsPanel.Visible = "off";
    app.CombatSupportPlatformsPanel_2.Visible = "off";
    app.CombatServiceSupportPlatformsPanel_2.Visible = "off";

    % Show the selected one
    switch selectedCategory
        case "Combat"
            app.CombatPlatformsPanel.Visible = "on";
        case "Combat Support"
            app.CombatSupportPlatformsPanel_2.Visible = "on";
        case "Combat Service Support"
            app.CombatServiceSupportPlatformsPanel_2.Visible = "on";
   
    end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.HealthEditField.Value=0;
            app.SpeedEditField.Value=0;
            app.FuelLevelEditField.Value=0;
            app.RangeEditField.Value=0;
            app.DetectionRadiusEditField.Value=0;
        end

        % Value changed function: VehicleDropDown
        function VehicleDropDownValueChanged(app, event)
     selectedVehicle = app.VehicleDropDown.Value;
     imgFolder = fileparts(mfilename("fullpath"));

    switch selectedVehicle
        case 'Bradley'
            app.Image_9.ImageSource = fullfile(imgFolder, 'BFV.jpg');
        case 'Abrams'
            app.Image_9.ImageSource = fullfile(imgFolder, 'M1A2.jpg');
        case 'DE M-SHORAD'
            app.Image_9.ImageSource = fullfile(imgFolder, 'DESHORAD.jpeg');
        case 'IFPC-HEL'
            app.Image_9.ImageSource = fullfile(imgFolder, 'IFPCHEL.png');
        otherwise
            app.Image_9.ImageSource = '';  % Clears image if not recognized
    end
        end

        % Value changed function: VehicleDropDown_2
        function VehicleDropDown_2ValueChanged(app, event)
             selectedVehicle = app.VehicleDropDown_2.Value;
    imgFolder = fileparts(mfilename("fullpath"));

    switch selectedVehicle
        case 'JLTV'
            app.Image_10.ImageSource = fullfile(imgFolder, 'JLTV.jpg');
        case 'Paladin'
            app.Image_10.ImageSource = fullfile(imgFolder, 'm109paladin.jpg');
        case 'Stryker'
            app.Image_10.ImageSource = fullfile(imgFolder, 'STRYKERICV.jpg');
        case 'Mortar Carrier'
            app.Image_10.ImageSource = fullfile(imgFolder, 'MORTARCARRIER.jpg');
        case 'ABV'
            app.Image_10.ImageSource = fullfile(imgFolder, 'ABV.jpg');
        otherwise
            app.Image_10.ImageSource = ''; % Clears image if not recognized
    end
        end

        % Value changed function: VehicleDropDown_3
        function VehicleDropDown_3ValueChanged(app, event)
    selectedVehicle = app.VehicleDropDown_3.Value;
    imgFolder = fileparts(mfilename("fullpath"));

    switch selectedVehicle
        case 'FMTV'
            app.Image_11.ImageSource = fullfile(imgFolder, 'FMTV.jpg');
        case 'HMMWV Ambulance'
            app.Image_11.ImageSource = fullfile(imgFolder, 'humveeambulance.jpg');
        case 'Tracked Ambulance'
            app.Image_11.ImageSource = fullfile(imgFolder, 'trackedambulance.jpeg');
        case 'PLS'
            app.Image_11.ImageSource = fullfile(imgFolder, 'PLS.jpg');
        case 'Hercules'
            app.Image_11.ImageSource = fullfile(imgFolder, 'M88HERCULES.jpg');
        otherwise
            app.Image_11.ImageSource = ''; % Clears image if not recognized
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.VehicleJSONGeneratorUIFigure = uifigure('Visible', 'off');
            app.VehicleJSONGeneratorUIFigure.Name = 'Vehicle JSON Generator';
            app.ImportButton = uibutton(app.VehicleJSONGeneratorUIFigure, 'push');
            app.ImportButton.Text = 'Import';
            app.ExportButton = uibutton(app.VehicleJSONGeneratorUIFigure, 'push');
            app.ExportButton.Text = 'Export';
            app.ResetButton = uibutton(app.VehicleJSONGeneratorUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CategoryDropDownLabel = uilabel(app.VehicleJSONGeneratorUIFigure);
            app.CategoryDropDownLabel.Text = 'Category';
            app.CategoryDropDown = uidropdown(app.VehicleJSONGeneratorUIFigure);
            app.CategoryDropDown.Items = {'(Blank)', 'Combat', 'Combat Support', 'Combat Service Support'};
            app.CategoryDropDown.ValueChangedFcn = createCallbackFcn(app, @CategoryDropDownValueChanged, true);
            app.CategoryDropDown.Value = '(Blank)';
            app.CombatPlatformsPanel = uipanel(app.VehicleJSONGeneratorUIFigure);
            app.CombatPlatformsPanel.Title = 'Combat Platforms';
            app.CombatPlatformsPanel.Visible = 'off';
            app.Image_9 = uiimage(app.CombatPlatformsPanel);
            app.Image_9.ImageSource = fullfile(pathToMLAPP, 'BFV.jpg');
            app.HealthEditFieldLabel = uilabel(app.CombatPlatformsPanel);
            app.HealthEditFieldLabel.Text = 'Health';
            app.HealthEditField = uieditfield(app.CombatPlatformsPanel, 'numeric');
            app.HealthEditField.Limits = [0 75];
            app.HealthEditField.Value = 1;
            app.SpeedEditFieldLabel = uilabel(app.CombatPlatformsPanel);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.CombatPlatformsPanel, 'numeric');
            app.FuelLevelEditFieldLabel = uilabel(app.CombatPlatformsPanel);
            app.FuelLevelEditFieldLabel.Text = 'Fuel Level';
            app.FuelLevelEditField = uieditfield(app.CombatPlatformsPanel, 'numeric');
            app.RangeEditFieldLabel = uilabel(app.CombatPlatformsPanel);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.CombatPlatformsPanel, 'numeric');
            app.DetectionRadiusLabel_2 = uilabel(app.CombatPlatformsPanel);
            app.DetectionRadiusLabel_2.Text = {'Detection'; 'Radius'};
            app.DetectionRadiusEditField = uieditfield(app.CombatPlatformsPanel, 'numeric');
            app.VariantNameEditFieldLabel = uilabel(app.CombatPlatformsPanel);
            app.VariantNameEditFieldLabel.Text = 'Variant Name';
            app.VariantNameEditField = uieditfield(app.CombatPlatformsPanel, 'text');
            app.VehicleDropDownLabel = uilabel(app.CombatPlatformsPanel);
            app.VehicleDropDownLabel.Text = 'Vehicle';
            app.VehicleDropDown = uidropdown(app.CombatPlatformsPanel);
            app.VehicleDropDown.Items = {'Bradley', 'Abrams', 'DE M-SHORAD', 'IFPC-HEL'};
            app.VehicleDropDown.ValueChangedFcn = createCallbackFcn(app, @VehicleDropDownValueChanged, true);
            app.VehicleDropDown.Value = 'Bradley';
            app.CombatSupportPlatformsPanel_2 = uipanel(app.VehicleJSONGeneratorUIFigure);
            app.CombatSupportPlatformsPanel_2.Title = 'Combat Support Platforms';
            app.CombatSupportPlatformsPanel_2.Visible = 'off';
            app.Image_10 = uiimage(app.CombatSupportPlatformsPanel_2);
            app.Image_10.ImageSource = fullfile(pathToMLAPP, 'Stryker_ICV_front_q.jpg');
            app.HealthEditField_2Label = uilabel(app.CombatSupportPlatformsPanel_2);
            app.HealthEditField_2Label.Text = 'Health';
            app.HealthEditField_2 = uieditfield(app.CombatSupportPlatformsPanel_2, 'numeric');
            app.SpeedEditField_2Label = uilabel(app.CombatSupportPlatformsPanel_2);
            app.SpeedEditField_2Label.Text = 'Speed';
            app.SpeedEditField_2 = uieditfield(app.CombatSupportPlatformsPanel_2, 'numeric');
            app.FuelLevelEditField_2Label = uilabel(app.CombatSupportPlatformsPanel_2);
            app.FuelLevelEditField_2Label.Text = 'Fuel Level';
            app.FuelLevelEditField_2 = uieditfield(app.CombatSupportPlatformsPanel_2, 'numeric');
            app.RangeEditField_3Label = uilabel(app.CombatSupportPlatformsPanel_2);
            app.RangeEditField_3Label.Text = 'Range';
            app.RangeEditField_3 = uieditfield(app.CombatSupportPlatformsPanel_2, 'numeric');
            app.DetectionRadiusEditField_2Label = uilabel(app.CombatSupportPlatformsPanel_2);
            app.DetectionRadiusEditField_2Label.Text = {'Detection'; 'Radius'};
            app.DetectionRadiusEditField_2 = uieditfield(app.CombatSupportPlatformsPanel_2, 'numeric');
            app.VariantNameEditField_2Label = uilabel(app.CombatSupportPlatformsPanel_2);
            app.VariantNameEditField_2Label.Text = 'Variant Name';
            app.VariantNameEditField_2 = uieditfield(app.CombatSupportPlatformsPanel_2, 'text');
            app.VehicleDropDown_2Label = uilabel(app.CombatSupportPlatformsPanel_2);
            app.VehicleDropDown_2Label.Text = 'Vehicle';
            app.VehicleDropDown_2 = uidropdown(app.CombatSupportPlatformsPanel_2);
            app.VehicleDropDown_2.Items = {'JLTV', 'Paladin', 'Stryker', 'Mortar Carrier', 'ABV'};
            app.VehicleDropDown_2.ValueChangedFcn = createCallbackFcn(app, @VehicleDropDown_2ValueChanged, true);
            app.VehicleDropDown_2.Value = 'JLTV';
            app.CombatServiceSupportPlatformsPanel_2 = uipanel(app.VehicleJSONGeneratorUIFigure);
            app.CombatServiceSupportPlatformsPanel_2.Title = 'Combat Service Support Platforms';
            app.CombatServiceSupportPlatformsPanel_2.Visible = 'off';
            app.Image_11 = uiimage(app.CombatServiceSupportPlatformsPanel_2);
            app.Image_11.ImageSource = fullfile(pathToMLAPP, 'FMTV.jpg');
            app.HealthEditField_3Label = uilabel(app.CombatServiceSupportPlatformsPanel_2);
            app.HealthEditField_3Label.Text = 'Health';
            app.HealthEditField_3 = uieditfield(app.CombatServiceSupportPlatformsPanel_2, 'numeric');
            app.SpeedEditField_3Label = uilabel(app.CombatServiceSupportPlatformsPanel_2);
            app.SpeedEditField_3Label.Text = 'Speed';
            app.SpeedEditField_3 = uieditfield(app.CombatServiceSupportPlatformsPanel_2, 'numeric');
            app.FuelLevelEditField_3Label = uilabel(app.CombatServiceSupportPlatformsPanel_2);
            app.FuelLevelEditField_3Label.Text = 'Fuel Level';
            app.FuelLevelEditField_3 = uieditfield(app.CombatServiceSupportPlatformsPanel_2, 'numeric');
            app.RangeEditField_5Label = uilabel(app.CombatServiceSupportPlatformsPanel_2);
            app.RangeEditField_5Label.Text = 'Range';
            app.RangeEditField_5 = uieditfield(app.CombatServiceSupportPlatformsPanel_2, 'numeric');
            app.DetectionRadiusEditField_3Label = uilabel(app.CombatServiceSupportPlatformsPanel_2);
            app.DetectionRadiusEditField_3Label.Text = {'Detection'; 'Radius'};
            app.DetectionRadiusEditField_3 = uieditfield(app.CombatServiceSupportPlatformsPanel_2, 'numeric');
            app.VariantNameEditField_3Label = uilabel(app.CombatServiceSupportPlatformsPanel_2);
            app.VariantNameEditField_3Label.Text = 'Variant Name';
            app.VariantNameEditField_3 = uieditfield(app.CombatServiceSupportPlatformsPanel_2, 'text');
            app.VehicleDropDown_3Label = uilabel(app.CombatServiceSupportPlatformsPanel_2);
            app.VehicleDropDown_3Label.Text = 'Vehicle';
            app.VehicleDropDown_3 = uidropdown(app.CombatServiceSupportPlatformsPanel_2);
            app.VehicleDropDown_3.Items = {'FMTV', 'HMMWV Ambulance', 'Tracked Ambulance', 'PLS', 'Hercules'};
            app.VehicleDropDown_3.Editable = 'on';
            app.VehicleDropDown_3.ValueChangedFcn = createCallbackFcn(app, @VehicleDropDown_3ValueChanged, true);
            app.VehicleDropDown_3.Value = 'FMTV';
            app.UAVPanel_2 = uipanel(app.VehicleJSONGeneratorUIFigure);
            app.UAVPanel_2.Title = 'UAV';
            app.Image_12 = uiimage(app.UAVPanel_2);
            app.Image_12.ImageSource = fullfile(pathToMLAPP, 'RQ11.jpg');
            app.HealthEditField_4Label = uilabel(app.UAVPanel_2);
            app.HealthEditField_4Label.Text = 'Health';
            app.HealthEditField_4 = uieditfield(app.UAVPanel_2, 'numeric');
            app.SpeedEditField_4Label = uilabel(app.UAVPanel_2);
            app.SpeedEditField_4Label.Text = 'Speed';
            app.SpeedEditField_4 = uieditfield(app.UAVPanel_2, 'numeric');
            app.FuelLevelEditField_4Label = uilabel(app.UAVPanel_2);
            app.FuelLevelEditField_4Label.Text = 'Fuel Level';
            app.FuelLevelEditField_4 = uieditfield(app.UAVPanel_2, 'numeric');
            app.RangeEditField_7Label = uilabel(app.UAVPanel_2);
            app.RangeEditField_7Label.Text = 'Range';
            app.RangeEditField_7 = uieditfield(app.UAVPanel_2, 'numeric');
            app.DetectionRadiusEditField_4Label = uilabel(app.UAVPanel_2);
            app.DetectionRadiusEditField_4Label.Text = {'Detection'; 'Radius'};
            app.DetectionRadiusEditField_4 = uieditfield(app.UAVPanel_2, 'numeric');
            app.SubcategoryButtonGroup_11 = uibuttongroup(app.UAVPanel_2);
            app.SubcategoryButtonGroup_11.Title = 'Subcategory';
            app.RavenButton_2 = uitogglebutton(app.SubcategoryButtonGroup_11);
            app.RavenButton_2.Text = 'Raven';
            app.RavenButton_2.Value = true;
            app.ShadowButton_2 = uitogglebutton(app.SubcategoryButtonGroup_11);
            app.ShadowButton_2.Text = 'Shadow';
            app.AltitudeEditFieldLabel = uilabel(app.UAVPanel_2);
            app.AltitudeEditFieldLabel.Text = 'Altitude';
            app.AltitudeEditField = uieditfield(app.UAVPanel_2, 'numeric');
            app.VariantNameEditField_4Label = uilabel(app.UAVPanel_2);
            app.VariantNameEditField_4Label.Text = 'Variant Name';
            app.VariantNameEditField_4 = uieditfield(app.UAVPanel_2, 'text');
            app.WeaponSystemsPanel = uipanel(app.VehicleJSONGeneratorUIFigure);
            app.WeaponSystemsPanel.Title = 'Weapon Systems';
            app.ConsumptionEditFieldLabel = uilabel(app.WeaponSystemsPanel);
            app.ConsumptionEditFieldLabel.Text = 'Consumption';
            app.ConsumptionEditField = uieditfield(app.WeaponSystemsPanel, 'numeric');
            app.CapacityEditFieldLabel = uilabel(app.WeaponSystemsPanel);
            app.CapacityEditFieldLabel.Text = 'Capacity';
            app.CapacityEditField = uieditfield(app.WeaponSystemsPanel, 'numeric');
            app.MinRequirementLabel = uilabel(app.WeaponSystemsPanel);
            app.MinRequirementLabel.Text = 'Min Requirement';
            app.MinRequirementEditField = uieditfield(app.WeaponSystemsPanel, 'numeric');
            app.RechargeRateLabel = uilabel(app.WeaponSystemsPanel);
            app.RechargeRateLabel.Text = 'Recharge Rate';
            app.RechargeRateEditField = uieditfield(app.WeaponSystemsPanel, 'numeric');
            app.PowerEditFieldLabel = uilabel(app.WeaponSystemsPanel);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.WeaponSystemsPanel, 'numeric');
            app.WeaponHealthEditFieldLabel = uilabel(app.WeaponSystemsPanel);
            app.WeaponHealthEditFieldLabel.Text = 'Weapon Health';
            app.WeaponHealthEditField = uieditfield(app.WeaponSystemsPanel, 'numeric');
            app.RangeEditField_2Label = uilabel(app.WeaponSystemsPanel);
            app.RangeEditField_2Label.Text = 'Range';
            app.RangeEditField_2 = uieditfield(app.WeaponSystemsPanel, 'numeric');
            app.EquippedWeaponDropDownLabel = uilabel(app.WeaponSystemsPanel);
            app.EquippedWeaponDropDownLabel.Text = 'Equipped Weapon';
            app.EquippedWeaponDropDown = uidropdown(app.WeaponSystemsPanel);
            app.EquippedWeaponDropDown.Items = {'None', 'DEW', 'RadioEnergy', 'Kinetic'};
            app.EquippedWeaponDropDown.Value = 'None';
            % Show the figure after all components are created
            app.VehicleJSONGeneratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VJG_25MAY25_A

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VehicleJSONGeneratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VehicleJSONGeneratorUIFigure)
        end
    end
end